classdef GUI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        StartData                      matlab.ui.control.Button
        StopDataButton                 matlab.ui.control.StateButton
        YawHeadingdegreeGauge          matlab.ui.control.SemicircularGauge
        YawHeadingdegreeGaugeLabel     matlab.ui.control.Label
        PitchdegreeGauge               matlab.ui.control.SemicircularGauge
        PitchdegreeGaugeLabel          matlab.ui.control.Label
        RolldegreeGauge                matlab.ui.control.SemicircularGauge
        RolldegreeLabel                matlab.ui.control.Label
        WheelSteeringAngledegreeGauge  matlab.ui.control.SemicircularGauge
        WheelSteeringAngledegreeGaugeLabel  matlab.ui.control.Label
        ThrottleGauge                  matlab.ui.control.LinearGauge
        ThrottleGaugeLabel             matlab.ui.control.Label
        RFWheelRPMGauge                matlab.ui.control.Gauge
        RFWheelRPMLabel                matlab.ui.control.Label
        LFWheelRPMGauge                matlab.ui.control.Gauge
        LFWheelRPMLabel                matlab.ui.control.Label
        LRWheelRPMGauge                matlab.ui.control.Gauge
        LRWheelRPMGaugeLabel           matlab.ui.control.Label
        RRWheelRPMGauge                matlab.ui.control.Gauge
        RRWheelRPMGaugeLabel           matlab.ui.control.Label
        BatteryPlot                    matlab.ui.control.UIAxes
        VelocityPlot                   matlab.ui.control.UIAxes
        GraphsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        WheelSpeedTab                  matlab.ui.container.Tab
        WheelSpeedGraph                matlab.ui.control.UIAxes
        VehicleSpeedTab                matlab.ui.container.Tab
        CellVoltageTab                 matlab.ui.container.Tab
        SetupTab                       matlab.ui.container.Tab
        GPSFixLamp                     matlab.ui.control.Lamp
        GPSFixLampLabel                matlab.ui.control.Label
        SSHCarNameEditField            matlab.ui.control.NumericEditField
        SSHCarNameEditFieldLabel       matlab.ui.control.Label
        SSHPortNumberEditField         matlab.ui.control.NumericEditField
        SSHPortNumberEditFieldLabel    matlab.ui.control.Label
        SSHPasswordEditField           matlab.ui.control.EditField
        SSHPasswordEditFieldLabel      matlab.ui.control.Label
        SSHAddressEditField            matlab.ui.control.EditField
        SSHAddressEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set EndDataCollection to be false
            
            %Set up velocity and acceleration plot
            yyaxis(app.VelocityPlot, 'right')
            ylabel(app.VelocityPlot, 'Acceleration (m/s^2)', Color = 'black')
            ylim(app.VelocityPlot, [-15,15])
            app.VelocityPlot.YColor = ('black');
            yticks(app.VelocityPlot, [-15, -10, -5, 0, 5, 10, 15])
            yyaxis(app.VelocityPlot, 'left')
            ylabel(app.VelocityPlot, 'Velocity (mph)', Color = 'black')
            ylim(app.VelocityPlot, [0,60])
            yticks(app.VelocityPlot, [0, 10, 20, 30, 40, 50, 60])
            app.VelocityPlot.YColor = ('black');
            xlim(app.VelocityPlot, [0, 60])

            %Set up battery voltage plot
            %yyaxis(app.BatteryPlot, 'left')
            ylabel(app.BatteryPlot, 'Cell Voltage (V)', Color = 'black')
            ylim(app.BatteryPlot, [0,5])
            xlim(app.BatteryPlot, [0, 60])


        end

        % Callback function: not associated with a component
        function ExitProgramButtonPushed(app, event)
            %While app running
             
            flag = 1;
            while flag
                flag = get(app.Switch, 'Value');
                drawnow
                if isequal(other_flag, 'Off')
                    flag = 0;
                    break
                end
             end
    
        end

        % Callback function: not associated with a component
        function StartStopDataCollectionButtonValueChanged(app, event)
            % value = app.StartStopDataCollectionButton.Value;
            % app.GPSFixLamp.Color = [1 1 1];
            % disp(value)
            % flag = 1;
            % %While app running
            % 
            % app.GPSFixLamp.Color = [0 0 1];
            % disp(flag)
            % while flag
            %     app.GPSFixLamp.Color = [1 0 1];
            %     other_flag = app.StartStopDataCollectionButton.Value;
            %     %other_flag = get(app.ExitProgramButton, 'state');
            %     disp(other_flag)
            %     if isequal(other_flag, 0)
            %         flag = 0;
            %         app.GPSFixLamp.Color = [1 0 0];
            %         break
            %     end
            %     % Get data from SSH
            %     % Initialize variables
            % 
            %     %value = app.StartStopDataCollectionButton.Value;
            % 
            %     %EndDataCollection = get(app.StopDataStreamingButtonPushed, 'Value');
            %  end

            flag = 1;
            while flag
                flag = get(app.StartStopDataCollectionButton, 'value');
                drawnow
                if isequal(other_flag, 'Off')
                    flag = 0;
                    break
                end
             end


                %app.GPSFixLamp.Color = [0 1 0];
                % Variable Assignment
                data = {};
                ThrottlePosition = 37;
                % % SteeringPWM = ;
                % % ThrottlePWM = ;
                LFWheelRPM = 1800;
                RFWheelRPM = 2400;
                LRWheelRPM = 800;
                RRWheelRPM = 0;
                % % Cell1Voltage = ;
                % % Cell2Voltage = ;
                % % Cell3Voltage = ;
                % % Cell4Voltage = ;
                % % Cell5Voltage = ;
                % % Cell6Voltage = ;
                % % RollAngle = ;
                % % PitchAngle = ;
                % % YawAngle = ;
                % % GPSFix = ;


                % Math
                % TotalVoltage = sum([Cell1Voltage Cell2Voltage Cell3Voltage Cell4Voltage Cell5Voltage Cell6Voltage]);
                % % SteeringAngle = ;
                % ThrottlePosition = (ThrottlePWM-1500)/500;

                % Plotting

                % Assingment of variables used for plotting
                RedlineWheelRPM = 2000;
                
                app.RolldegreeGauge.Value = 6;

                %Left Front wheel RPM plotting
                app.LFWheelRPMGauge.Value = LFWheelRPM;
                if LFWheelRPM > RedlineWheelRPM
                    app.LFWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                    app.LFWheelRPMGauge.ScaleColorLimits = [2000 LFWheelRPM;0 2000];
                 else
                    app.LFWheelRPMGauge.ScaleColors = [0 1 0];
                    app.LFWheelRPMGauge.ScaleColorLimits = [-1 LFWheelRPM];
                end

                %Right front wheel RPM plotting
                app.RFWheelRPMGauge.Value = RFWheelRPM;
                if RFWheelRPM > RedlineWheelRPM
                    app.RFWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                    app.RFWheelRPMGauge.ScaleColorLimits = [2000 RFWheelRPM;0 2000];
                else
                    app.RFWheelRPMGauge.ScaleColors = [0 1 0];
                    app.RFWheelRPMGauge.ScaleColorLimits = [-1 RFWheelRPM];
                end

                %Left rear wheel RPM plotting
                app.LRWheelRPMGauge.Value = LRWheelRPM;
                if LRWheelRPM > RedlineWheelRPM
                    app.LRWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                    app.LRWheelRPMGauge.ScaleColorLimits = [2000 LRWheelRPM;0 2000];
                else
                    app.LRWheelRPMGauge.ScaleColors = [0 1 0];
                    app.LRWheelRPMGauge.ScaleColorLimits = [-1 LRWheelRPM];
                end
                
                %Right rear wheel RPM plotting
                app.RRWheelRPMGauge.Value = RRWheelRPM;
                if RRWheelRPM > RedlineWheelRPM
                    app.RRWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                    app.RRWheelRPMGauge.ScaleColorLimits = [2000 RRWheelRPM;0 2000];
                else
                    app.RRWheelRPMGauge.ScaleColors = [0 1 0];
                    app.RRWheelRPMGauge.ScaleColorLimits = [-1 RRWheelRPM];
                end
                
                %Plot throttle
                app.ThrottleGauge.Value = ThrottlePosition;
                 if ThrottlePosition > 0
                    app.ThrottleGauge.ScaleColors = [0 1 0];
                    app.ThrottleGauge.ScaleColorLimits = [0 ThrottlePosition];
                 else
                    app.ThrottleGauge.ScaleColors = [1 0 0];
                    app.ThrottleGauge.ScaleColorLimits = [ThrottlePosition, 0];
                 end
            
        end

        % Button pushed function: StartData
        function StartDataButtonPushed(app, event)
            RedlineWheelRPM = 2000;
            flag = 1;
            app.StopDataButton.Value = true;
            i=0
            while flag
                flag = get(app.StopDataButton, 'value');
                drawnow
                app.RFWheelRPMGauge.Value = i;
                if i > RedlineWheelRPM
                    app.RFWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                    app.RFWheelRPMGauge.ScaleColorLimits = [2000 i;0 2000];
                else
                    app.RFWheelRPMGauge.ScaleColors = [0 1 0];
                    app.RFWheelRPMGauge.ScaleColorLimits = [-1 i];
                end
                if isequal(flag, 'Off')
                    flag = 0;
                    break
                end
                i = i+1;
            end
            app.RFWheelRPMGauge.Value = 3000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'autumn');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.VelocityPlot = uiaxes(app.HomeTab);
            title(app.VelocityPlot, 'Vehicle Velocity and Acceleration')
            xlabel(app.VelocityPlot, 'Time (s)')
            ylabel(app.VelocityPlot, 'Y')
            zlabel(app.VelocityPlot, 'Z')
            app.BatteryPlot = uiaxes(app.HomeTab);
            title(app.BatteryPlot, 'Battery Voltage')
            xlabel(app.BatteryPlot, 'Time (s)')
            ylabel(app.BatteryPlot, 'Y')
            zlabel(app.BatteryPlot, 'Z')
            app.RRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.RRWheelRPMGaugeLabel.Text = 'RR Wheel RPM';
            app.RRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RRWheelRPMGauge.Limits = [0 2500];
            app.RRWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.RRWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.LRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.LRWheelRPMGaugeLabel.Text = 'LR Wheel RPM';
            app.LRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LRWheelRPMGauge.Limits = [0 2500];
            app.LRWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.LFWheelRPMLabel = uilabel(app.HomeTab);
            app.LFWheelRPMLabel.Text = 'LF Wheel RPM';
            app.LFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LFWheelRPMGauge.Limits = [0 2500];
            app.LFWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.LFWheelRPMGauge.MajorTickLabels = {'0 600 1900 2500'};
            app.LFWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.RFWheelRPMLabel = uilabel(app.HomeTab);
            app.RFWheelRPMLabel.Text = 'RF Wheel RPM';
            app.RFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RFWheelRPMGauge.Limits = [0 2500];
            app.RFWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.ThrottleGaugeLabel = uilabel(app.HomeTab);
            app.ThrottleGaugeLabel.Interpreter = 'latex';
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.HomeTab, 'linear');
            app.ThrottleGauge.Limits = [-100 100];
            app.ThrottleGauge.MajorTicks = [-100 -75 -50 -25 0 25 50 75 100];
            app.ThrottleGauge.MajorTickLabels = {'-100%', '-75%', '-50%', '-25%', '0%', '25%', '50%', '75%', '100%'};
            app.ThrottleGauge.MinorTicks = [-100 -87 -75 -62.5 -50 -37.5 -25 -12.5 0 12.5 25 37.5 50 62.5 75 87.5 100];
            app.WheelSteeringAngledegreeGaugeLabel = uilabel(app.HomeTab);
            app.WheelSteeringAngledegreeGaugeLabel.Interpreter = 'latex';
            app.WheelSteeringAngledegreeGaugeLabel.Text = 'Wheel Steering Angle (\degree)';
            app.WheelSteeringAngledegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.WheelSteeringAngledegreeGauge.Limits = [-45 45];
            app.WheelSteeringAngledegreeGauge.MinorTicks = [];
            app.RolldegreeLabel = uilabel(app.HomeTab);
            app.RolldegreeLabel.Interpreter = 'latex';
            app.RolldegreeLabel.Text = 'Roll (\degree)';
            app.RolldegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.RolldegreeGauge.Limits = [-20 20];
            app.RolldegreeGauge.MajorTicks = [-20 -10 0 10 20];
            app.RolldegreeGauge.MajorTickLabels = {'-20', '-10', '0', '10', '20'};
            app.RolldegreeGauge.MinorTicks = [-15 -5 5 15];
            app.PitchdegreeGaugeLabel = uilabel(app.HomeTab);
            app.PitchdegreeGaugeLabel.Interpreter = 'latex';
            app.PitchdegreeGaugeLabel.Text = 'Pitch (\degree)';
            app.PitchdegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.PitchdegreeGauge.Limits = [-45 45];
            app.PitchdegreeGauge.MinorTicks = 0;
            app.YawHeadingdegreeGaugeLabel = uilabel(app.HomeTab);
            app.YawHeadingdegreeGaugeLabel.Interpreter = 'latex';
            app.YawHeadingdegreeGaugeLabel.Text = 'Yaw/Heading (\degree)';
            app.YawHeadingdegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.YawHeadingdegreeGauge.Limits = [-180 180];
            app.YawHeadingdegreeGauge.MajorTicks = [-180 -90 0 90 180];
            app.YawHeadingdegreeGauge.MinorTicks = [-180 -135 -45 0 45 135 180];
            app.StopDataButton = uibutton(app.HomeTab, 'state');
            app.StopDataButton.Text = 'Stop Data';
            app.StartData = uibutton(app.HomeTab, 'push');
            app.StartData.ButtonPushedFcn = createCallbackFcn(app, @StartDataButtonPushed, true);
            app.StartData.Text = 'Start Data';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.WheelSpeedTab = uitab(app.TabGroup2);
            app.WheelSpeedTab.Title = 'Wheel Speed';
            app.WheelSpeedGraph = uiaxes(app.WheelSpeedTab);
            title(app.WheelSpeedGraph, 'Wheel Speed vs Time')
            xlabel(app.WheelSpeedGraph, 'dt')
            ylabel(app.WheelSpeedGraph, 'RPM')
            zlabel(app.WheelSpeedGraph, 'Z')
            app.VehicleSpeedTab = uitab(app.TabGroup2);
            app.VehicleSpeedTab.Title = 'Vehicle Speed';
            app.CellVoltageTab = uitab(app.TabGroup2);
            app.CellVoltageTab.Title = 'Cell Voltage';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SSHAddressEditFieldLabel = uilabel(app.SetupTab);
            app.SSHAddressEditFieldLabel.Text = 'SSH Address';
            app.SSHAddressEditField = uieditfield(app.SetupTab, 'text');
            app.SSHAddressEditField.Value = 'rcCar';
            app.SSHPasswordEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPasswordEditFieldLabel.Text = 'SSH Password';
            app.SSHPasswordEditField = uieditfield(app.SetupTab, 'text');
            app.SSHPasswordEditField.Value = 'admin';
            app.SSHPortNumberEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPortNumberEditFieldLabel.Text = 'SSH Port Number';
            app.SSHPortNumberEditField = uieditfield(app.SetupTab, 'numeric');
            app.SSHCarNameEditFieldLabel = uilabel(app.SetupTab);
            app.SSHCarNameEditFieldLabel.Text = 'SSH Car Name';
            app.SSHCarNameEditField = uieditfield(app.SetupTab, 'numeric');
            app.GPSFixLampLabel = uilabel(app.SetupTab);
            app.GPSFixLampLabel.Text = 'GPS Fix';
            app.GPSFixLamp = uilamp(app.SetupTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end